classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Gauge       matlab.ui.control.LinearGauge
        statusLamp  matlab.ui.control.Lamp
        Label       matlab.ui.control.Label
        Switch      matlab.ui.control.Switch
        graph       matlab.ui.control.UIAxes
    end

    
 properties (Access = private)
       dataX = [0,1,3,1,0,-1,-3,-1,0,1,3,1,0] %dummy graph
       plot1, value1
       testF = 79 % dumy Freq
      isButtonEnable = false;
      poolObj
    end

    
    methods (Access = private)
        function start(app)
                %app.plot1 = plot(app.graph, app.dataX,"Color","b");
                note = app.frequencyToNote(app.testF);
                freq = app.noteToFreq(note);
                app.Label.Text = note;
                %parfeval(app.poolObj, @app.bck, 0);
                app.bck();
        end
        function bck(app) 
            u = udpport("Byte", "IPV4", "LocalPort", 8080, "EnablePortSharing", true);
            fs = 24000;
            N = 240;
            t = (0:N-1);
            frequencies = t * (fs / N);
            WindowLen = N*3;
            AnalysisLen = N;
            SynthesisLen = round(AnalysisLen*2^(-6/12));
            player = audioDeviceWriter('SampleRate',fs, 'SupportVariableSizeInput',true, 'BufferSize',1024, 'BitDepth', '24-bit integer');
            win = sqrt(hanning(WindowLen,'periodic'));
            ats = audioTimeScaler(AnalysisLen/SynthesisLen,'Window',win,'OverlapLength',WindowLen-AnalysisLen);
            hamming_window = hann(N)';
            hamming_window2 = hann(N)';
            i = 0;
            Fc = 150;
            Wn = Fc/(fs/2); % Normalized cutoff frequency (0 < Wn < 1)
            order = 3; % Filter order (you can choose an appropriate order)
            [b, a] = butter(order, Wn, 'high'); % High-pass filter
            while app.value1
                x = read(u,N,"int32");
                x = bitshift(x, -8, "int32");
                x = x / 8388608.0 * 30;
                %playAudio(x, fs);
                x = filter(b, a, x);
                windowed_signal = x .* hamming_window;
                fft_result = fft(windowed_signal);
                magnitude_spectrum = abs(fft_result);
                [~,index_max] = max(magnitude_spectrum(1:length(magnitude_spectrum)/2));
                r = x';
                realF = max(60, frequencies(index_max));
                
                noteF = noteToFreq(app, frequencyToNote(app, realF));
                disp(realF);
                disp(noteF);
                y = stretchAudio(r, realF/realF, 'Window',hamming_window2','OverlapLength',20);
                z = resample(y, realF, realF);
                player(z);
                drawnow;
                if (i == 100)
                    xdddd(app, frequencies, magnitude_spectrum, realF, noteF);
                    i = 0;
                end
                i = i + 1;
            end
        end
        function xdddd(app, frequencies, magnitude_spectrum, realF, noteF)
            plot(app.graph, frequencies, magnitude_spectrum,"Color","b")
            app.Label.Text = frequencyToNote(app, realF);
            app.Gauge.Value= realF-noteF;
        end
        function clearPlot(app)
            delete(app.plot1);
            app.Gauge.Value=0;
            app.Label.Text = "";
        end

        function note = frequencyToNote(~,frequency)
            % Define the standard tuning frequency (A4)
            A1 = 55;
            
            % Calculate the note index
            noteIndex = round(12 * log2(frequency / A1))+ 13;

            % Define the mapping of note indices to note names
            noteNames = {'A0', 'A#0', 'B0', 'C1', 'C#1', 'D1', 'D#1', 'E1', 'F1', 'F#1', 'G1', 'G#1', 'A1', 'A#1', 'B1', 'C2', 'C#2', 'D2', 'D#2', 'E2', 'F2', 'F#2', 'G2', 'G#2', 'A2', 'A#2', 'B2', 'C3', 'C#3', 'D3', 'D#3', 'E3', 'F3', 'F#3', 'G3', 'G#3', 'A3', 'A#3', 'B3', 'C4', 'C#4', 'D4', 'D#4', 'E4', 'F4', 'F#4', 'G4', 'G#4', 'A4', 'A#4', 'B4', 'C5', 'C#5', 'D5', 'D#5', 'E5', 'F5', 'F#5', 'G5', 'G#5', 'A5', 'A#5', 'B5', 'C6', 'C#6', 'D6', 'D#6', 'E6', 'F6', 'F#6', 'G6', 'G#6', 'A6', 'A#6', 'B6', 'C7', 'C#7', 'D7', 'D#7', 'E7', 'F7', 'F#7', 'G7', 'G#7', 'A7', 'A#7', 'B7', 'C8', 'C#8', 'D8', 'D#8', 'E8', 'F8', 'F#8', 'G8', 'G#8', 'A8', 'A#8', 'B8'};

            % Get the note name
            if noteIndex < 1
                noteIndex = 1;
            elseif noteIndex > 90
                noteIndex = 90;
            end
            note = noteNames{noteIndex};
            end

       function freq = noteToFreq(~,target_note)
           note_freq = {'A0', 28; 'A#0', 29; 'B0', 31; 'C1', 33; 'C#1', 35; 'D1', 37; 'D#1', 39; 'E1', 41; 'F1', 44; 'F#1', 46; 'G1', 49; 'G#1', 52; 'A1', 55; 'A#1', 58; 'B1', 62; ...
             'C2', 65; 'C#2', 69; 'D2', 73; 'D#2', 78; 'E2', 82; 'F2', 87; 'F#2', 92; 'G2', 98; 'G#2', 104; 'A2', 110; 'A#2', 117; 'B2', 123; ...
             'C3', 131; 'C#3', 139; 'D3', 147; 'D#3', 156; 'E3', 165; 'F3', 175; 'F#3', 185; 'G3', 196; 'G#3', 208; 'A3', 220; 'A#3', 233; 'B3', 247; ...
             'C4', 262; 'C#4', 277; 'D4', 294; 'D#4', 311; 'E4', 330; 'F4', 349; 'F#4', 370; 'G4', 392; 'G#4', 415; 'A4', 440; 'A#4', 466; 'B4', 494; ...
             'C5', 523; 'C#5', 554; 'D5', 587; 'D#5', 622; 'E5', 659; 'F5', 698; 'F#5', 740; 'G5', 784; 'G#5', 831; 'A5', 880; 'A#5', 932; 'B5', 988; ...
             'C6', 1047; 'C#6', 1109; 'D6', 1175; 'D#6', 1245; 'E6', 1319; 'F6', 1397; 'F#6', 1480; 'G6', 1568; 'G#6', 1661; 'A6', 1760; 'A#6', 1865; 'B6', 1976; ...
             'C7', 2093; 'C#7', 2217; 'D7', 2349; 'D#7', 2489; 'E7', 2637; 'F7', 2794; 'F#7', 2960; 'G7', 3136; 'G#7', 3322; 'A7', 3520; 'A#7', 3729; 'B7', 3951; ...
             'C8', 4186; 'C#8', 4435; 'D8', 4699; 'D#8', 4978; 'E8', 5274; 'F8', 5588; 'F#8', 5920; 'G8', 6272; 'G#8', 6645; 'A8', 7040; 'A#8', 7459; 'B8', 7902};
           index = strcmp(note_freq(:, 1), target_note);
           freq = note_freq{index, 2};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %app.poolObj = gcp('nocreate');
            %if isempty(app.poolObj)
                %app.poolObj = parpool; % Create a parallel pool
            %end
         
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.value1 = app.Switch.Value;
            if app.value1 == true
                app.statusLamp.Color = "green";
                start(app);
            else
                clearPlot(app);
                app.statusLamp.Color = "red";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Auto Tune')
            xlabel(app.graph, 'Frequency')
            ylabel(app.graph, 'Amplitude')
            zlabel(app.graph, 'Z')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = [false true];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = false;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.statusLamp = uilamp(app.UIFigure);
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [-20 20];
            app.Gauge.MajorTicks = [-20 -10 0 10 20];
            app.Gauge.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end